classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainGridLayout               matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        Tab1                         matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        App1ControlPanel             matlab.ui.container.Panel
        SegmentLicensePlateCheckBox  matlab.ui.control.CheckBox
        DarkLicensePlateCheckBox     matlab.ui.control.CheckBox
        LisencePlate                 matlab.ui.control.Label
        LisencePlateLabel            matlab.ui.control.Label
        App1LoadPanel                matlab.ui.container.Panel
        App1SaveFileButton           matlab.ui.control.Button
        App1FileName                 matlab.ui.control.Label
        App1LoadFileButton           matlab.ui.control.Button
        App1InputLabel               matlab.ui.control.Label
        App1InputImage               matlab.ui.control.Image
        App1OutputLabel              matlab.ui.control.Label
        App1OutputImage              matlab.ui.control.Image
    end

    
    properties (Access = private)
        I = truecolorload('images\plates\IMG-20180108-WA0043.jpg');
        filename = "No File Selected"

        O = truecolorload('images\plates\IMG-20180108-WA0043.jpg');

        edgeDetection = "Laplacian";
        c = [238 69 54];
    end

    methods (Static)
    
        function result = rgbtogray(I)
            result = rgb2gray(I);
            result(:,:,2) = result(:,:,1);
            result(:,:,3) = result(:,:,1);
        end

    end
    
    methods (Access = private)
        
        function loadImage(app)
        end

        function saveImage(~, im)
            % Get file name
            [file, path] = uiputfile({ ...
                '*.bmp', 'BMP files (*.bmp)'; ...
                '*.gif', 'GIF files (*.gif)'; ...
                '*.jpg', 'JPEG files (*jpg)'; ...
                '*.png', 'PNG files (*.png)'; ...
                '*.tif', 'TIFF files (*tif)' ...
            });
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);

            imwrite(im, filepath);
        end

        function updateApp1Values(app)
            app.App1InputImage.ImageSource = app.I;
            s = size(app.I);

            % Get variables
            segment = app.SegmentLicensePlateCheckBox.Value;
            dark = app.DarkLicensePlateCheckBox.Value;

            if (segment)
                try
                    g = rgb2gray(app.I);
                    candidates = find_plate_candidates(g, 10, dark);
                    [~, ~, lisencePlate] = locate_plate(g, candidates, 3, 4.25);
                    app.O = lisencePlate;
                catch err
                    disp(err);
                    app.O = rgb2gray(app.I);
                end
            else
                app.O = rgb2gray(app.I);
            end

            app.O = imbinarize(app.O);

            if not(dark)
                app.O = not(app.O);
            end

            app.O(:,:,2) = app.O;
            app.O(:,:,3) = app.O(:,:,1);
            app.O = double(app.O);

            [regions, bboxes] = plate_detection(app.O);
            result = plate_vision(regions);

            if isempty(result)
                app.LisencePlate.Text = "Not found";
            else
                app.LisencePlate.Text = result;

                figure; imshow(app.O);
                hold on
                for k = 1:numel(bboxes)
                    BB = bboxes{k};
                    rectangle('Position', [BB(1),BB(2),BB(3),BB(4)],'EdgeColor','g','LineWidth',1) ;
                end
                hold off
                F = getframe;
                app.O = frame2im(F);
                close;
            end
            
            % Outputs
            app.App1OutputImage.ImageSource = app.O;
            app.App1FileName.Text = app.filename;
        end

        
        function updateBasedOnTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.Tab1
                app.updateApp1Values();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateBasedOnTab();
        end

        % Button pushed function: App1LoadFileButton
        function App1LoadFileButtonPushed(app, event)
            % Load to I
            [file, path] = uigetfile('*');
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);
            app.filename = filepath;
            app.I = truecolorload(filepath);

            app.updateApp1Values();
        end

        % Button pushed function: App1SaveFileButton
        function App1SaveFileButtonPushed(app, event)
            app.saveImage(app.O);
        end

        % Value changed function: DarkLicensePlateCheckBox, 
        % SegmentLicensePlateCheckBox
        function App1Update(app, event)
            app.updateApp1Values();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Number Plate Detection';
            app.GridLayout = uigridlayout(app.Tab1);
            app.App1OutputImage = uiimage(app.GridLayout);
            app.App1OutputImage.ImageSource = 'sample02.jpg';
            app.App1OutputLabel = uilabel(app.GridLayout);
            app.App1OutputLabel.Text = 'Detected License Plate';
            app.App1InputImage = uiimage(app.GridLayout);
            app.App1InputImage.ImageSource = 'IMG-20180108-WA0043.jpg';
            app.App1InputLabel = uilabel(app.GridLayout);
            app.App1InputLabel.Text = 'Input Image';
            app.App1ControlPanel = uipanel(app.GridLayout);
            app.App1LoadPanel = uipanel(app.App1ControlPanel);
            app.App1LoadFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1LoadFileButtonPushed, true);
            app.App1LoadFileButton.Text = 'Load File';
            app.App1FileName = uilabel(app.App1LoadPanel);
            app.App1FileName.Text = 'No File Selected';
            app.App1SaveFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1SaveFileButtonPushed, true);
            app.App1SaveFileButton.Text = 'Save File';
            app.LisencePlateLabel = uilabel(app.App1ControlPanel);
            app.LisencePlateLabel.Text = 'Detected License Plate:';
            app.LisencePlate = uilabel(app.App1ControlPanel);
            app.LisencePlate.Text = '-';
            app.DarkLicensePlateCheckBox = uicheckbox(app.App1ControlPanel);
            app.DarkLicensePlateCheckBox.ValueChangedFcn = createCallbackFcn(app, @App1Update, true);
            app.DarkLicensePlateCheckBox.Text = 'Dark License Plate';
            app.DarkLicensePlateCheckBox.Value = true;
            app.SegmentLicensePlateCheckBox = uicheckbox(app.App1ControlPanel);
            app.SegmentLicensePlateCheckBox.ValueChangedFcn = createCallbackFcn(app, @App1Update, true);
            app.SegmentLicensePlateCheckBox.Text = 'Segment License Plate';
            app.SegmentLicensePlateCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end